classdef control_loop_visualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        ControlsGridLayout  matlab.ui.container.GridLayout
        PositionSlider      matlab.ui.control.Slider
        VelocitySlider      matlab.ui.control.Slider
        PositionSpinner     matlab.ui.control.Spinner
        VelocitySpinner     matlab.ui.control.Spinner
        PositionEditField   matlab.ui.control.EditField
        VelocityEditField   matlab.ui.control.EditField
        RightPanel          matlab.ui.container.Panel
        GraphGridLayout     matlab.ui.container.GridLayout
        PositionUIAxes      matlab.ui.control.UIAxes
        VelocityUIAxes      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Property % Description
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: PositionSlider
        function PositionSliderValueChanging(app, event)
            changingValue = event.Value;
            
            app.PositionSpinner.Value = changingValue; % Change the spinner value
            
            % Change reference position value
            
        end

        % Value changing function: VelocitySlider
        function VelocitySliderValueChanging(app, event)
            changingValue = event.Value;
            
            app.VelocitySpinner.Value = changingValue; % Change the spinner value
            
            % Change reference velocity value
            
        end

        % Value changed function: PositionSpinner
        function PositionSpinnerValueChanged(app, event)
            value = app.PositionSpinner.Value;
            
            app.PositionSlider.Value = value; % Change the slider position
            
            % Change reference position value
            
        end

        % Value changed function: VelocitySpinner
        function VelocitySpinnerValueChanged(app, event)
            value = app.VelocitySpinner.Value;
            
            app.VelocitySlider.Value = value; % Change the slider position
            
            % Change reference velocity value
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {748, 748};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {321, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ControlsGridLayout = uigridlayout(app.LeftPanel);
            app.PositionSlider = uislider(app.ControlsGridLayout);
            app.PositionSlider.Limits = [0 180];
            app.PositionSlider.ValueChangingFcn = createCallbackFcn(app, @PositionSliderValueChanging, true);
            app.VelocitySlider = uislider(app.ControlsGridLayout);
            app.VelocitySlider.Limits = [-90 90];
            app.VelocitySlider.ValueChangingFcn = createCallbackFcn(app, @VelocitySliderValueChanging, true);
            app.PositionSpinner = uispinner(app.ControlsGridLayout);
            app.PositionSpinner.ValueChangedFcn = createCallbackFcn(app, @PositionSpinnerValueChanged, true);
            app.VelocitySpinner = uispinner(app.ControlsGridLayout);
            app.VelocitySpinner.ValueChangedFcn = createCallbackFcn(app, @VelocitySpinnerValueChanged, true);
            app.PositionEditField = uieditfield(app.ControlsGridLayout, 'text');
            app.PositionEditField.Value = 'Position (deg)';
            app.VelocityEditField = uieditfield(app.ControlsGridLayout, 'text');
            app.VelocityEditField.Value = 'Velocity (deg/s)';
            app.RightPanel = uipanel(app.GridLayout);
            app.GraphGridLayout = uigridlayout(app.RightPanel);
            app.PositionUIAxes = uiaxes(app.GraphGridLayout);
            title(app.PositionUIAxes, '')
            xlabel(app.PositionUIAxes, 'Time (s)')
            ylabel(app.PositionUIAxes, 'Position (deg)')
            app.PositionUIAxes.XLim = [0 10];
            app.PositionUIAxes.YLim = [-90 180];
            app.PositionUIAxes.YTick = [-60 0 60 120 180];
            app.VelocityUIAxes = uiaxes(app.GraphGridLayout);
            title(app.VelocityUIAxes, '')
            xlabel(app.VelocityUIAxes, 'Time (s)')
            ylabel(app.VelocityUIAxes, 'Velocity (deg/s)')
            app.VelocityUIAxes.XLim = [0 10];
            app.VelocityUIAxes.YLim = [-90 360];
            app.VelocityUIAxes.YTick = [-60 0 60 120 180 240 300 360];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = control_loop_visualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end